classdef filterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageFilterVisualizerUIFigure  matlab.ui.Figure
        UIAxesFilter                   matlab.ui.control.UIAxes
        FilterButtonGroup              matlab.ui.container.ButtonGroup
        AllpassButton                  matlab.ui.control.RadioButton
        LowpassButton                  matlab.ui.control.RadioButton
        HighpassButton                 matlab.ui.control.RadioButton
        BandpassButton                 matlab.ui.control.RadioButton
        CutoffFrequencySliderLabel     matlab.ui.control.Label
        CutoffFrequencySlider          matlab.ui.control.Slider
        UIAxesImage                    matlab.ui.control.UIAxes
        FiltercoefficientsTextAreaLabel  matlab.ui.control.Label
        FilterCoefficientsTextArea     matlab.ui.control.TextArea
        CutoffFrequencySliderLabel_lo  matlab.ui.control.Label
        CutoffFrequencySlider_lo       matlab.ui.control.Slider
        CutoffFrequencySliderLabel_hi  matlab.ui.control.Label
        CutoffFrequencySlider_hi       matlab.ui.control.Slider
        OrderLabel                     matlab.ui.control.Label
        OrderSpinner                   matlab.ui.control.Spinner
        UIAxesImageFFT                 matlab.ui.control.UIAxes
        NormalizeCheckBox              matlab.ui.control.CheckBox
        InvertCheckBox                 matlab.ui.control.CheckBox
    end

    % My own functions etc.
    methods (Access = private)
    
        % Design a filter based on the GUI input (order, type, cutoff)
        function filter = generateFilter(app, filterOrder, filterType, cutoff)
            
            % Frequency / amplitude vectors (1x4)
            frequencySpectrum  = zeros(1, 4); % Normalized frequency spectrum: 0 to 1
            amplitudeSpec      = zeros(1, 4); % Amplitude spectrum: 0 to 1 (0 to 100%)
            
            % Constants
            cutoffAdjustment = 0.05;
            
            % Generate the filter based on the selected filter type
            switch filterType
                case 'Lowpass' 
                    frequencySpectrum(1) = 0;
                    frequencySpectrum(2) = cutoff;
                    frequencySpectrum(3) = cutoff + cutoffAdjustment;
                    frequencySpectrum(4) = 1;
                    
                    % ‾‾\__
                    amplitudeSpec(1:2) = 1; % Frequencies up to the cutoff is set to 1 (rest 0)
                    filter = firls(filterOrder, frequencySpectrum, amplitudeSpec);
                case 'Highpass'
                    frequencySpectrum(1) = 0;
                    frequencySpectrum(2) = cutoff - cutoffAdjustment;
                    frequencySpectrum(3) = cutoff;
                    frequencySpectrum(4) = 1;
                    
                    % __/‾‾
                    amplitudeSpec(3:4) = 1;
                    filter = firls(filterOrder, frequencySpectrum, amplitudeSpec);
                case 'Bandpass' % Two cutoff frequencies
                    frequencySpectrum(1) = 0;
                    frequencySpectrum(2) = cutoff(1) - cutoffAdjustment;
                    frequencySpectrum(3) = cutoff(2) + cutoffAdjustment;
                    frequencySpectrum(4) = 1;
                    
                    % _/‾‾\_
                    amplitudeSpec(2:3) = 1;
                    filter = firls(filterOrder, frequencySpectrum, amplitudeSpec);
                case 'Allpass'
                    frequencySpectrum(1) = 0;
                    frequencySpectrum(2) = 0.33;
                    frequencySpectrum(3) = 0.66;
                    frequencySpectrum(4) = 1;
                    
                    % ‾‾‾‾‾‾
                    amplitudeSpec = ones(1, 4);
                    filter = firls(filterOrder, frequencySpectrum, amplitudeSpec);
            end
        end
        
        function updateFilterResponse(app)
            cutoff = getSliderValue(app); % Get the cutoff frequency
            filterType = app.FilterButtonGroup.SelectedObject.Text; % Extract the radio button text
            filterOrder = app.OrderSpinner.Value;
            
            % Generate the filter and plot it
            filter = generateFilter(app, filterOrder, filterType, cutoff);
            app.FilterCoefficientsTextArea.Value = mat2str(round(filter, 2));
            plotFilterResponse(app, filter);
        end
        
        function plotFilterResponse(app, taps)
            % Plot the filter response
            [H,f] = freqz(taps, 1, 128, 2);  % Create the frequency response
            plot(app.UIAxesFilter, f, abs(H)); % Plot the magnitude frequency response
            xlim(app.UIAxesFilter, [0 1])
            ylim(app.UIAxesFilter, [-0.1 1.1])
            %xlabel(app.UIAxesFilter, 'Normalized Frequency (\times\pi rad/sample)');
            %ylabel(app.UIAxesFilter, 'Magnitude');
        end
        
        % Get, round, set and return the value of the active slider(s) 
        function value = getSliderValue(app)
            filterType = app.FilterButtonGroup.SelectedObject.Text; % Extract the radio button text

            % Get and round slider value
            if strcmp(filterType, 'Bandpass')
                % TODO: Handle two cutoff frequencies
                
                % Cutoff 1
                value1 = app.CutoffFrequencySlider_lo.Value;
                value1 = round(value1, 1); app.CutoffFrequencySlider_lo.Value = value1;
                % Cutoff 2
                value2 = app.CutoffFrequencySlider_hi.Value;
                value2 = round(value2, 1); app.CutoffFrequencySlider_hi.Value = value2;
                
                value = [value1, value2]; % Create a vector
            else
                value = app.CutoffFrequencySlider.Value; % Extract the cutoff slider value
                value = round(value, 1); app.CutoffFrequencySlider.Value = value; % Round and set cutoff
            end
        end
        
        % Show or hide sliders depending on the filter type currently selected
        function updateSliderVisibility(app)
            filterType = app.FilterButtonGroup.SelectedObject.Text; % Extract the radio button text
            
            % Display the double sliders when bandpass
            if strcmp(filterType, 'Bandpass')
                showDoubleSliders(app);
            else
                hideDoubleSliders(app);
            end
        end
        
        % Hide the double cutoff frequency sliders (visible for bandpass)
        function hideDoubleSliders(app)
            % Show the regular slider
            app.CutoffFrequencySlider.Visible = 'on';
            app.CutoffFrequencySliderLabel.Visible = 'on';
            
            % Hide the double sliders
            app.CutoffFrequencySlider_lo.Visible = 'off';
            app.CutoffFrequencySlider_hi.Visible = 'off';
            app.CutoffFrequencySliderLabel_lo.Visible = 'off';
            app.CutoffFrequencySliderLabel_hi.Visible = 'off';
        end
        
        % Show the double cutoff frequency sliders (visible for bandpass)
        function showDoubleSliders(app)
            % Hide the regular slider
            app.CutoffFrequencySlider.Visible = 'off';
            app.CutoffFrequencySliderLabel.Visible = 'off';
            
            % Show the double sliders
            app.CutoffFrequencySlider_lo.Visible = 'on';
            app.CutoffFrequencySlider_hi.Visible = 'on';
            app.CutoffFrequencySliderLabel_lo.Visible = 'on';
            app.CutoffFrequencySliderLabel_hi.Visible = 'on';
        end
        
        % Display an image
        function updateImagePlot(app, imageToShow)
            imshow(imageToShow,'Parent',app.UIAxesImage);
        end
        
        % Filter the image (the original image)
        function filterImage(app, imageToFilter)
            % Generate the filter
            cutoff = getSliderValue(app); % Get the cutoff frequency
            filterType = app.FilterButtonGroup.SelectedObject.Text; % Extract the radio button text
            filterOrder = app.OrderSpinner.Value;
            filter = generateFilter(app, filterOrder, filterType, cutoff);
            
            % Transform 1D to 2D filter
            twoDimFilter = ftrans2(filter);
            
            % Padding method: pMethod: the padding method (symmetric, replicate, circular)
            pMethod = 'replicate';
            
            % Filter the image
            filteredImage = imfilter(imageToFilter, twoDimFilter, pMethod);

            % Display the filtered image
            % Adjust according to the Normalize checkbox
            normalizeChecked = app.NormalizeCheckBox.Value;
            invertChecked = app.InvertCheckBox.Value;
            if(invertChecked)
                filteredImage = imcomplement(filteredImage);
            end
            if(normalizeChecked) % Normalize
                imshow(filteredImage, [], 'Parent', app.UIAxesImage);
            else % Don't normalize
                imshow(filteredImage, 'Parent', app.UIAxesImage);
            end
            
            % Analyze the image using FFT
            fft2Analysis(app, filteredImage);
        end
        
        function fft2Analysis(app, imageToFFT)
            %% 2-D FFT analysis
            imageFFT = fftshift(fft2(imageToFFT));
            magnitudeFFT = log(abs(imageFFT));
            phaseFFT = angle(imageFFT);
            
            % Display the magnitude (log) of the FFT
            imshow(magnitudeFFT, [], 'Parent', app.UIAxesImageFFT);
            
            %imshow(phaseFFT, [], 'Parent', app.UIAxesImageFFTphase);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateSliderVisibility(app);
            updateFilterResponse(app);
            
            imageFromFile = imread('cameraman.tif');
            updateImagePlot(app, imageFromFile);
            fft2Analysis(app, imageFromFile);
        end

        % Value changed function: CutoffFrequencySlider, 
        % CutoffFrequencySlider_hi, CutoffFrequencySlider_lo
        function CutoffFrequencySliderValueChanged(app, event)
            updateFilterResponse(app);
            
            imageFromFile = imread('cameraman.tif');
            filterImage(app, imageFromFile);
        end

        % Selection changed function: FilterButtonGroup
        function FilterButtonGroupSelectionChanged(app, event)
            updateSliderVisibility(app);
            updateFilterResponse(app);
            
            imageFromFile = imread('cameraman.tif');
            filterImage(app, imageFromFile);
        end

        % Value changed function: InvertCheckBox
        function InvertCheckBoxValueChanged(app, event)
            %value = app.InvertCheckBox.Value;
            imageFromFile = imread('cameraman.tif');
            filterImage(app, imageFromFile);
        end

        % Callback function
        function NormalizeCheckBoxFFTValueChanged(app, event)
            %value = app.NormalizeCheckBoxFFT.Value;
            imageFromFile = imread('cameraman.tif');
            filterImage(app, imageFromFile);
        end

        % Value changed function: NormalizeCheckBox
        function NormalizeCheckBoxValueChanged(app, event)
            %value = app.NormalizeCheckBox.Value;
            imageFromFile = imread('cameraman.tif');
            filterImage(app, imageFromFile);
        end

        % Value changed function: OrderSpinner
        function OrderSpinnerValueChanged(app, event)
            updateFilterResponse(app);
            
            imageFromFile = imread('cameraman.tif');
            filterImage(app, imageFromFile);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageFilterVisualizerUIFigure = uifigure;
            app.ImageFilterVisualizerUIFigure.Name = 'Image Filter Visualizer';
            app.UIAxesFilter = uiaxes(app.ImageFilterVisualizerUIFigure);
            title(app.UIAxesFilter, 'Filter response')
            xlabel(app.UIAxesFilter, 'Normalized frequency')
            ylabel(app.UIAxesFilter, 'Magnitude')
            app.FilterButtonGroup = uibuttongroup(app.ImageFilterVisualizerUIFigure);
            app.FilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterButtonGroupSelectionChanged, true);
            app.FilterButtonGroup.Title = 'Filter';
            app.AllpassButton = uiradiobutton(app.FilterButtonGroup);
            app.AllpassButton.Text = 'Allpass';
            app.AllpassButton.Value = true;
            app.LowpassButton = uiradiobutton(app.FilterButtonGroup);
            app.LowpassButton.Text = 'Lowpass';
            app.HighpassButton = uiradiobutton(app.FilterButtonGroup);
            app.HighpassButton.Text = 'Highpass';
            app.BandpassButton = uiradiobutton(app.FilterButtonGroup);
            app.BandpassButton.Text = 'Bandpass';
            app.CutoffFrequencySliderLabel = uilabel(app.ImageFilterVisualizerUIFigure);
            app.CutoffFrequencySliderLabel.Text = 'Cutoff  frequency';
            app.CutoffFrequencySlider = uislider(app.ImageFilterVisualizerUIFigure);
            app.CutoffFrequencySlider.Limits = [0 1];
            app.CutoffFrequencySlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.CutoffFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencySliderValueChanged, true);
            app.CutoffFrequencySlider.MinorTicks = [];
            app.CutoffFrequencySlider.Value = 0.5;
            app.UIAxesImage = uiaxes(app.ImageFilterVisualizerUIFigure);
            title(app.UIAxesImage, 'Spatial domain')
            app.FiltercoefficientsTextAreaLabel = uilabel(app.ImageFilterVisualizerUIFigure);
            app.FiltercoefficientsTextAreaLabel.Text = 'Filter coefficients';
            app.FilterCoefficientsTextArea = uitextarea(app.ImageFilterVisualizerUIFigure);
            app.FilterCoefficientsTextArea.Editable = 'off';
            app.CutoffFrequencySliderLabel_lo = uilabel(app.ImageFilterVisualizerUIFigure);
            app.CutoffFrequencySliderLabel_lo.Text = {'Cutoff  frequency'; '(low)'};
            app.CutoffFrequencySlider_lo = uislider(app.ImageFilterVisualizerUIFigure);
            app.CutoffFrequencySlider_lo.Limits = [0 1];
            app.CutoffFrequencySlider_lo.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.CutoffFrequencySlider_lo.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencySliderValueChanged, true);
            app.CutoffFrequencySlider_lo.MinorTicks = [];
            app.CutoffFrequencySlider_lo.Value = 0.5;
            app.CutoffFrequencySliderLabel_hi = uilabel(app.ImageFilterVisualizerUIFigure);
            app.CutoffFrequencySliderLabel_hi.Text = {'Cutoff  frequency'; '(high)'};
            app.CutoffFrequencySlider_hi = uislider(app.ImageFilterVisualizerUIFigure);
            app.CutoffFrequencySlider_hi.Limits = [0 1];
            app.CutoffFrequencySlider_hi.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.CutoffFrequencySlider_hi.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencySliderValueChanged, true);
            app.CutoffFrequencySlider_hi.MinorTicks = [];
            app.CutoffFrequencySlider_hi.Value = 0.5;
            app.OrderLabel = uilabel(app.ImageFilterVisualizerUIFigure);
            app.OrderLabel.Text = 'Order:';
            app.OrderSpinner = uispinner(app.ImageFilterVisualizerUIFigure);
            app.OrderSpinner.Step = 2;
            app.OrderSpinner.ValueChangedFcn = createCallbackFcn(app, @OrderSpinnerValueChanged, true);
            app.OrderSpinner.Limits = [4 128];
            app.OrderSpinner.RoundFractionalValues = 'on';
            app.OrderSpinner.ValueDisplayFormat = '%11.3g';
            app.OrderSpinner.Value = 16;
            app.UIAxesImageFFT = uiaxes(app.ImageFilterVisualizerUIFigure);
            title(app.UIAxesImageFFT, 'Frequency domain')
            app.NormalizeCheckBox = uicheckbox(app.ImageFilterVisualizerUIFigure);
            app.NormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeCheckBoxValueChanged, true);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.NormalizeCheckBox.Value = true;
            app.InvertCheckBox = uicheckbox(app.ImageFilterVisualizerUIFigure);
            app.InvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertCheckBoxValueChanged, true);
            app.InvertCheckBox.Text = 'Invert';
        end
    end

    methods (Access = public)

        % Construct app
        function app = filterApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageFilterVisualizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageFilterVisualizerUIFigure)
        end
    end
end